classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Image          matlab.ui.control.Image
        Label_4        matlab.ui.control.Label
        Button_15      matlab.ui.control.Button
        Button_14      matlab.ui.control.Button
        Button_13      matlab.ui.control.Button
        Button_12      matlab.ui.control.Button
        Button_11      matlab.ui.control.Button
        zhengButton    matlab.ui.control.Button
        JakesButton_2  matlab.ui.control.Button
        ClarkeButton   matlab.ui.control.Button
        Button_10      matlab.ui.control.Button
        Button_9       matlab.ui.control.Button
        Button_8       matlab.ui.control.Button
        NakagamiLabel  matlab.ui.control.Label
        Label_3        matlab.ui.control.Label
        Label_2        matlab.ui.control.Label
        Button_7       matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Label          matlab.ui.control.Label
        AWGNLabel      matlab.ui.control.Label
        JakesButton    matlab.ui.control.Button
        ZhengButton    matlab.ui.control.Button
        AWCSWBButton   matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            run Inverse_cumulative_transformation_method.m
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            run linear_con_linear_feed_and_boxmuller.m
        end

        % Button pushed function: AWCSWBButton
        function AWCSWBButtonPushed(app, event)
            run awc_swb_and_boxmuller.m
        end

        % Button pushed function: JakesButton
        function JakesButtonPushed(app, event)
            run Jakes_main.m
        end

        % Button pushed function: ZhengButton
        function ZhengButtonPushed(app, event)
            run Zheng_main.m
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            run Multipath_sim_time_doppler.m
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            run Multipath_sim_freq_doppler.m
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            run Multipath_sim_time.m
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            run Multipath_sim_freq.m
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            run Ni.m
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            run XZBbaoluo.m
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            run baoluo.m
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            run untitled9.m
        end

        % Button pushed function: ClarkeButton
        function ClarkeButtonPushed(app, event)
            run Clarke.m
        end

        % Button pushed function: JakesButton_2
        function JakesButton_2Pushed(app, event)
            run Jakes.m
        end

        % Button pushed function: zhengButton
        function zhengButtonPushed(app, event)
            run Zheng.m
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            run compare.m
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            run Inverse_Method.m
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            run Uniform_Hat_func.m
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '逆变换';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '线性同余法与线性反馈移位寄存器法';
            app.AWCSWBButton = uibutton(app.UIFigure, 'push');
            app.AWCSWBButton.ButtonPushedFcn = createCallbackFcn(app, @AWCSWBButtonPushed, true);
            app.AWCSWBButton.Text = 'AWC算法与SWB算法';
            app.ZhengButton = uibutton(app.UIFigure, 'push');
            app.ZhengButton.ButtonPushedFcn = createCallbackFcn(app, @ZhengButtonPushed, true);
            app.ZhengButton.Text = 'Zheng';
            app.JakesButton = uibutton(app.UIFigure, 'push');
            app.JakesButton.ButtonPushedFcn = createCallbackFcn(app, @JakesButtonPushed, true);
            app.JakesButton.Text = 'Jakes';
            app.AWGNLabel = uilabel(app.UIFigure);
            app.AWGNLabel.Text = 'AWGN信道';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '多经信道';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '多经多普勒时域信道';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '多经多普勒频域信道';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '多经时域信道';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '多经频域信道';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '逆变换法';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '莱斯信道';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '瑞利信道';
            app.NakagamiLabel = uilabel(app.UIFigure);
            app.NakagamiLabel.Text = 'Nakagami信道';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '正弦波叠加法';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '统计特性';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '方法对比';
            app.ClarkeButton = uibutton(app.UIFigure, 'push');
            app.ClarkeButton.ButtonPushedFcn = createCallbackFcn(app, @ClarkeButtonPushed, true);
            app.ClarkeButton.Text = 'Clarke模型';
            app.JakesButton_2 = uibutton(app.UIFigure, 'push');
            app.JakesButton_2.ButtonPushedFcn = createCallbackFcn(app, @JakesButton_2Pushed, true);
            app.JakesButton_2.Text = 'Jakes模型';
            app.zhengButton = uibutton(app.UIFigure, 'push');
            app.zhengButton.ButtonPushedFcn = createCallbackFcn(app, @zhengButtonPushed, true);
            app.zhengButton.Text = 'zheng模型';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '方法对比';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.Text = '方法对比';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '逆变换法';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '舍取法';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.Text = '信道分解法';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '模拟信道生成器';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end